classdef layers_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        LoadTab                      matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        StatusPanel                  matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        LoadImagePanel               matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        LoadButton                   matlab.ui.control.Button
        LoadBackgroundPanel          matlab.ui.container.Panel
        GridLayout6_2                matlab.ui.container.GridLayout
        LoadButton_3                 matlab.ui.control.Button
        DrawButton_2                 matlab.ui.control.Button
        ImageTab                     matlab.ui.container.Tab
        GridLayout2_2                matlab.ui.container.GridLayout
        ChooseFrameLabel             matlab.ui.control.Label
        ChooseFrameSlider            matlab.ui.control.Slider
        LayersButtonGroup            matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.ToggleButton
        Button2                      matlab.ui.control.ToggleButton
        Button3                      matlab.ui.control.ToggleButton
        LayerDataPanel               matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        HideCheckBox                 matlab.ui.control.CheckBox
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        DropDown2Label               matlab.ui.control.Label
        DropDown2                    matlab.ui.control.DropDown
        ImageDataPanel               matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        LoadButton_2                 matlab.ui.control.Button
        BackgroundImageLabel         matlab.ui.control.Label
        ColorForNaNLabel             matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        RValue                       matlab.ui.control.Spinner
        GValue                       matlab.ui.control.Spinner
        BValue                       matlab.ui.control.Spinner
        ShowColorbarCheckBox         matlab.ui.control.CheckBox
        ColorbarTitleEditFieldLabel  matlab.ui.control.Label
        ColorbarTitleEditField       matlab.ui.control.EditField
        ColorbarAxisScaleLabel       matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        ColorbarAxisMin              matlab.ui.control.NumericEditField
        ColorbarAxisMax              matlab.ui.control.NumericEditField
        ImageTitleEditFieldLabel     matlab.ui.control.Label
        ImageTitleEditField          matlab.ui.control.EditField
        ShowLegendCheckBox           matlab.ui.control.CheckBox
        DrawButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImageLoaderComponentHandler ImageLoaderComponent
        ImageComponentHandler ImageComponentHandler
        ImageDisplayHandler ImageDisplayHandler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO addpath
            addpath('..\classDefinitions\')
            imageBuilder=ImageBuilder("Z:\Analysis\users\Projects\Iris\Codes\matlab-utility-functions");
            app.ImageDisplayHandler=ImageDisplayHandler;
            app.ImageComponentHandler=ImageComponentHandler(app.ImageDisplayHandler,imageBuilder);
            app.ImageLoaderComponentHandler=ImageLoaderComponent(app.ImageComponentHandler,imageBuilder);
            
            %to move
            app.ImageTab.Parent=[];
            app.ImageLoaderComponentHandler.updateStatusTable(app);
            app.DrawButton_2.Enable=false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ImageLoaderComponentHandler.loadImageData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ImageDisplayHandler.forceCloseFigures();
            delete(app);
        end

        % Value changed function: ChooseFrameSlider
        function ChooseFrameSliderValueChanged(app, event)
            value = app.ChooseFrameSlider.Value;
            app.ImageDisplayHandler.setFrame(app, round(value));
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.ImageComponentHandler.renderFromInput(app);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            app.ImageLoaderComponentHandler.loadBackground(app);
        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
            app.ImageLoaderComponentHandler.showLoadedData(app);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.ImageComponentHandler.loadBackground();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.GridLayout2 = uigridlayout(app.LoadTab);
            app.StatusPanel = uipanel(app.GridLayout2);
            app.StatusPanel.Title = 'Status';
            app.GridLayout7 = uigridlayout(app.StatusPanel);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {''; 'Loaded'; 'Frames'};
            app.UITable.RowName = {};
            app.LoadImagePanel = uipanel(app.GridLayout2);
            app.LoadImagePanel.Title = 'Load Image';
            app.GridLayout6 = uigridlayout(app.LoadImagePanel);
            app.LoadButton = uibutton(app.GridLayout6, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadBackgroundPanel = uipanel(app.GridLayout2);
            app.LoadBackgroundPanel.Title = 'Load Background';
            app.GridLayout6_2 = uigridlayout(app.LoadBackgroundPanel);
            app.LoadButton_3 = uibutton(app.GridLayout6_2, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.DrawButton_2 = uibutton(app.GridLayout2, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout2_2 = uigridlayout(app.ImageTab);
            app.ChooseFrameLabel = uilabel(app.GridLayout2_2);
            app.ChooseFrameLabel.Text = 'Choose Frame:';
            app.ChooseFrameSlider = uislider(app.GridLayout2_2);
            app.ChooseFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseFrameSliderValueChanged, true);
            app.LayersButtonGroup = uibuttongroup(app.GridLayout2_2);
            app.LayersButtonGroup.Title = 'Layers';
            app.Button = uitogglebutton(app.LayersButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.LayersButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.LayersButtonGroup);
            app.Button3.Text = 'Button3';
            app.LayerDataPanel = uipanel(app.GridLayout2_2);
            app.LayerDataPanel.Title = 'Layer Data';
            app.GridLayout3 = uigridlayout(app.LayerDataPanel);
            app.HideCheckBox = uicheckbox(app.GridLayout3);
            app.HideCheckBox.Text = 'Hide';
            app.DropDownLabel = uilabel(app.GridLayout3);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown2Label = uilabel(app.GridLayout3);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.GridLayout3);
            app.ImageDataPanel = uipanel(app.GridLayout2_2);
            app.ImageDataPanel.Title = 'Image Data';
            app.GridLayout3_2 = uigridlayout(app.ImageDataPanel);
            app.LoadButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.BackgroundImageLabel = uilabel(app.GridLayout3_2);
            app.BackgroundImageLabel.Text = 'Background Image';
            app.ColorForNaNLabel = uilabel(app.GridLayout3_2);
            app.ColorForNaNLabel.Text = 'Color For NaN';
            app.GridLayout4 = uigridlayout(app.GridLayout3_2);
            app.RValue = uispinner(app.GridLayout4);
            app.RValue.Step = 0.1;
            app.RValue.Limits = [0 1];
            app.GValue = uispinner(app.GridLayout4);
            app.GValue.Step = 0.1;
            app.GValue.Limits = [0 1];
            app.BValue = uispinner(app.GridLayout4);
            app.BValue.Step = 0.1;
            app.BValue.Limits = [0 1];
            app.ShowColorbarCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowColorbarCheckBox.Text = 'Show Colorbar';
            app.ColorbarTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ColorbarTitleEditFieldLabel.Text = 'Colorbar Title';
            app.ColorbarTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ColorbarAxisScaleLabel = uilabel(app.GridLayout3_2);
            app.ColorbarAxisScaleLabel.Text = 'Colorbar Axis Scale';
            app.GridLayout5 = uigridlayout(app.GridLayout3_2);
            app.ColorbarAxisMin = uieditfield(app.GridLayout5, 'numeric');
            app.ColorbarAxisMax = uieditfield(app.GridLayout5, 'numeric');
            app.ImageTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ImageTitleEditFieldLabel.Text = 'Image Title';
            app.ImageTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.DrawButton = uibutton(app.GridLayout2_2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layers_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end